classdef lineEstimationSimuliation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ComputedLinePositioncmEditField  matlab.ui.control.NumericEditField
        ComputedLinePositioncmEditFieldLabel  matlab.ui.control.Label
        RightSensorADCnormEditField   matlab.ui.control.NumericEditField
        RightSensorADCnormEditFieldLabel  matlab.ui.control.Label
        LeftSensorADCnormEditField    matlab.ui.control.NumericEditField
        LeftSensorADCnormEditFieldLabel  matlab.ui.control.Label
        SensorSpacingcmDropDown       matlab.ui.control.DropDown
        SensorSpacingcmDropDownLabel  matlab.ui.control.Label
        LinePositionSlider            matlab.ui.control.Slider
        LinePositionSliderLabel       matlab.ui.control.Label
        SensorHeightSlider            matlab.ui.control.Slider
        SensorHeightSliderLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = linspace(-5, 5, 1000); % X limit - boundaries (-10,10) cm
        elements_p_trend;
        sigma = 0.2; % standard deviance (simulated) in cm!

    end
    
    methods (Access = private)
        
        function updatePlot(app)
            

            % estimatedLinePos = (rightResponse - leftResponse) / (rightResponse + leftResponse) * (sensorSpacing / 2);
             

        end
        
        function computeLinePos(app)
            % Compute position of Line based of the normalized ADC values
            % get Y from the value
            y_estimation = app.LeftSensorADCnormEditField.Value + app.RightSensorADCnormEditField.Value;
            % get position
            x_position = (y_estimation-app.elements_p_trend(2))/app.elements_p_trend(1);
            % write the result
            app.ComputedLinePositioncmEditField.Value = x_position;
            % update the line graph
            app.LinePositionSlider.Value = x_position;
            
            % draw the point on trend
            DrawLinePos(app, x_position, y_estimation);

            updateLine(app);
        end

        function computeADC(app)
            mu = str2double(app.SensorSpacingcmDropDown.Value) / 2;
            
            % function mirroring
            x_position_from_slider = app.LinePositionSlider.Value;
            if(app.LinePositionSlider.Value >= mu)
                x_position_from_slider = 2*mu-x_position_from_slider;
            elseif(app.LinePositionSlider.Value <= -mu)
                x_position_from_slider = -2*mu-x_position_from_slider;
            end

            % compute the ADC value from Line X pos
            y_estimation = app.elements_p_trend(1)*x_position_from_slider + app.elements_p_trend(2);

            % draw the marker
            DrawLinePos(app, x_position_from_slider, y_estimation);
        
            % set the value to field from slider
            app.ComputedLinePositioncmEditField.Value = x_position_from_slider;
        end
        
        function updateGlobalPlot(app)

            % Get parameters from GUI
            sensorHeight = app.SensorHeightSlider.Value;

            % Draw simulated Sensor response
 
            % mean is center of the sensor
            mu = str2double(app.SensorSpacingcmDropDown.Value) / 2;

            % simulate the sensor as gaussian distribution (can be
            % different)
            rightResponse = -(1/(app.sigma*sqrt(2*pi))) * exp(-((app.x-mu).^2)/(2*app.sigma^2));
            leftResponse = (1/(app.sigma*sqrt(2*pi))) * exp(-((app.x-(-mu)).^2)/(2*app.sigma^2));

            % saturation to 1 (ADC max value after normalization)
            rightResponse(rightResponse <= -1) = -1;
            leftResponse(leftResponse >= 1) = 1;

            % Compute the Linearization trend
            x_sensorRepose = [app.x(app.x >= -mu & app.x <= 0) app.x(app.x > 0 & app.x <= mu)];
            y_sensorRepose = [leftResponse(app.x >= -mu & app.x <= 0) rightResponse(app.x > 0 & app.x < mu)];
            % 1. degree
            app.elements_p_trend = polyfit(x_sensorRepose, y_sensorRepose, 1);
            x_fit_trend = linspace(-mu, mu, 1000);
            y_linear = polyval(app.elements_p_trend, x_fit_trend);

            % Draw to the graph
            cla(app.UIAxes);
            ylim(app.UIAxes, [-1.3, 1]);
            hold(app.UIAxes, "on");
            % draw sensor responses
            plot(app.UIAxes, app.x, leftResponse, 'r', 'LineWidth', 2);
            plot(app.UIAxes, app.x, rightResponse, 'g', 'LineWidth', 2);
            % draw trend
            plot(app.UIAxes, x_fit_trend, y_linear, 'b', 'LineWidth', 3);

            % draw line
            updateLine(app);

            % draw robot
            rectBody = rectangle(app.UIAxes, 'Position', [-4, -1.3, 8, 0.1]);
            rectBody.FaceColor = "c";
            rectBody.FaceAlpha = 0.2;
            rectLeft = rectangle(app.UIAxes, 'Position', [-mu-0.7, -1.2, 1.4, 0.1]);
            rectLeft.FaceColor = "r";
            rectLeft.FaceAlpha = 0.2;
            rectLeft = rectangle(app.UIAxes, 'Position', [mu-0.7, -1.2, 1.4, 0.1]);
            rectLeft.FaceColor = "g";
            rectLeft.FaceAlpha = 0.2;
        end
        
        % draw or update the Line
        function updateLine(app)
            % delete previous
            delete(findobj(app.UIAxes, 'Tag', 'Line'));
            delete(findobj(app.UIAxes, 'Tag', 'LineFill'));

            line = plot(app.UIAxes, ones(1,2)*app.LinePositionSlider.Value, [-1.2,1], "k-.");
            line.Tag = "Line";
            rectLine = rectangle(app.UIAxes, 'Position', [app.LinePositionSlider.Value-0.55, -1.2, 1.1, 2.2]);
            rectLine.FaceColor = "k";
            rectLine.FaceAlpha = 0.2;
            rectLine.Tag = "LineFill";
        end
        
        % draw the position on line
        function DrawLinePos(app, x_position, y_position)
            delete(findobj(app.UIAxes, 'Tag', 'computedPos_point'));

            computedPosPlot = scatter(app.UIAxes, x_position, y_position, 100, "mx", 'LineWidth', 2);
            computedPosPlot.Tag = 'computedPos_point';
        end

        % draw the markers on Sensor response
        function DrawSensorResponseMarkers(app)
            delete(findobj(app.UIAxes, 'Tag', 'leftSensorMarker'));
            delete(findobj(app.UIAxes, 'Tag', 'rightSensorMarker'));
            
            % mean is center of the sensor
            mu = str2double(app.SensorSpacingcmDropDown.Value) / 2;
            
            % Definition of rectangles
            sensorLeftRect = [-mu-0.7, -1.2, 1.4, 0.1];  
            sensorRightRect = [mu-0.7, -1.2, 1.4, 0.1];
            LineRect = [app.LinePositionSlider.Value-0.55, -1.2, 1.1, 2.2];

            % Compute the Percetage Intersection two rectangles
            percentLeft = rectangle_intersection(app, sensorLeftRect, LineRect);
            percentRight = rectangle_intersection(app, sensorRightRect, LineRect);
            % need to normalize (0-100)
            percentLeft = (percentLeft / 78.5714) * 100;
            percentRight = (percentRight / 78.5714) * 100;
            
            % Ensure values are within (0,1) range for norminv
            percentLeft = max(min(percentLeft, 99.99), 0.01); % Prevent exact 0 or 100
            percentRight = max(min(percentRight, 99.99), 0.01);

            if percentLeft == 99.99
                x_left = -mu;
            else
                x_left = norminv(1-percentLeft/100, -mu, app.sigma)/2;
            end
            
            if percentRight == 99.99
                x_right = mu;
            else
                x_right = norminv(percentRight/100, mu, app.sigma)/2;
            end
            
            % Compute y from Gaussian function
            y_max = 1 / (app.sigma * sqrt(2 * pi));
            y_left = y_max * exp(-((x_left - (-mu)).^2) / (2 * app.sigma^2));
            if(y_left >= 1)
                y_left = 1;
            end

            y_right = -y_max * exp(-((x_right - mu).^2) / (2 * app.sigma^2));
            if(y_right <= -1)
                y_right = -1;
            end

            % draw the values
            leftSensorMarker = scatter(app.UIAxes, x_left, y_left, 50, "mo", 'LineWidth', 2);
            leftSensorMarker.Tag = 'leftSensorMarker';
            rightSensorMarker = scatter(app.UIAxes, x_right, y_right, 50, "mo", 'LineWidth', 2);
            rightSensorMarker.Tag = 'rightSensorMarker';

            % set Values to inputfield
            app.LeftSensorADCnormEditField.Value = y_left;
            app.RightSensorADCnormEditField.Value = y_right;

        end

        function percent_intersection = rectangle_intersection(app, rect1, rect2)
            % rect1 and rect2 are in format [x, y, width, height]
            
            % Compute the surface of intersection
            inter_area = rectint(rect1, rect2);
        
            % compute surface original rectangles
            area1 = rect1(3) * rect1(4);
            area2 = rect2(3) * rect2(4);
        
            % Get percentage of intersection of rectangles
            percent_intersection = (inter_area / min(area1, area2)) * 100;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % plot the main charts (consturct the environment)
            updateGlobalPlot(app);

            % compute Line position for default
            computeLinePos(app);

            % compute the responses
            DrawSensorResponseMarkers(app);

        end

        % Value changed function: LinePositionSlider
        function LinePositionSliderValueChanged(app, event)
            % update the chart
            updateLine(app);

            % update the Line Pos
            computeADC(app);
            % draw corner markers
            DrawSensorResponseMarkers(app);
        end

        % Value changed function: SensorSpacingcmDropDown
        function SensorSpacingcmDropDownValueChanged(app, event)
            % update the main chart
            updateGlobalPlot(app);    

            % compute Line position
            computeLinePos(app);
            % draw corner markers
            DrawSensorResponseMarkers(app);

        end

        % Value changed function: SensorHeightSlider
        function SensorHeightSliderValueChanged(app, event)
            updatePlot(app);
        end

        % Value changed function: LeftSensorADCnormEditField
        function LeftSensorADCnormEditFieldValueChanged(app, event)
            % max = 1 & min = 0
            if(app.LeftSensorADCnormEditField.Value > 1)
                app.LeftSensorADCnormEditField.Value = 1;
            elseif(app.LeftSensorADCnormEditField.Value < 0)
                app.LeftSensorADCnormEditField.Value = 0;
            end

            % compute the position of Line and update the visualization
            computeLinePos(app);            
        end

        % Value changed function: RightSensorADCnormEditField
        function RightSensorADCnormEditFieldValueChanged(app, event)
            % compute the position of Line and update the visualization

            % max = 0 & min = -1
            if(app.RightSensorADCnormEditField.Value > 0)
                app.RightSensorADCnormEditField.Value = 0;
            elseif(app.RightSensorADCnormEditField.Value < -1)
                app.RightSensorADCnormEditField.Value = -1;
            end

            % compute the position of Line and update the visualization
            computeLinePos(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Line Estimator')
            xlabel(app.UIAxes, 'X [cm]')
            ylabel(app.UIAxes, 'ADC_{norm} [-]')
            zlabel(app.UIAxes, 'Z')
            app.SensorHeightSliderLabel = uilabel(app.UIFigure);
            app.SensorHeightSliderLabel.Text = 'Sensor Height';
            app.SensorHeightSlider = uislider(app.UIFigure);
            app.SensorHeightSlider.ValueChangedFcn = createCallbackFcn(app, @SensorHeightSliderValueChanged, true);
            app.LinePositionSliderLabel = uilabel(app.UIFigure);
            app.LinePositionSliderLabel.Text = 'Line Position';
            app.LinePositionSlider = uislider(app.UIFigure);
            app.LinePositionSlider.Limits = [-4 4];
            app.LinePositionSlider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.LinePositionSlider.MajorTickLabels = {'-10', '-9', '-8', '-7', '-6', '-5', '-4', '-3', '-2', '-1', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.LinePositionSlider.ValueChangedFcn = createCallbackFcn(app, @LinePositionSliderValueChanged, true);
            app.LinePositionSlider.MinorTicks = [-10 -9.8 -9.6 -9.4 -9.2 -9 -8.8 -8.6 -8.4 -8.2 -8 -7.8 -7.6 -7.4 -7.2 -7 -6.8 -6.6 -6.4 -6.2 -6 -5.8 -5.6 -5.4 -5.2 -5 -4.8 -4.6 -4.4 -4.2 -4 -3.8 -3.6 -3.4 -3.2 -3 -2.8 -2.6 -2.4 -2.2 -2 -1.8 -1.6 -1.4 -1.2 -1 -0.800000000000001 -0.6 -0.4 -0.199999999999999 0 0.199999999999999 0.4 0.6 0.800000000000001 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.SensorSpacingcmDropDownLabel = uilabel(app.UIFigure);
            app.SensorSpacingcmDropDownLabel.Text = 'Sensor Spacing [cm]';
            app.SensorSpacingcmDropDown = uidropdown(app.UIFigure);
            app.SensorSpacingcmDropDown.Items = {'3', '2'};
            app.SensorSpacingcmDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorSpacingcmDropDownValueChanged, true);
            app.SensorSpacingcmDropDown.Value = '2';
            app.LeftSensorADCnormEditFieldLabel = uilabel(app.UIFigure);
            app.LeftSensorADCnormEditFieldLabel.Text = {'Left Sensor ADCnorm'; '[-]'};
            app.LeftSensorADCnormEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftSensorADCnormEditField.ValueDisplayFormat = '%.2f';
            app.LeftSensorADCnormEditField.ValueChangedFcn = createCallbackFcn(app, @LeftSensorADCnormEditFieldValueChanged, true);
            app.RightSensorADCnormEditFieldLabel = uilabel(app.UIFigure);
            app.RightSensorADCnormEditFieldLabel.Text = {'Right Sensor ADCnorm'; '[-]'};
            app.RightSensorADCnormEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightSensorADCnormEditField.ValueDisplayFormat = '%.2f';
            app.RightSensorADCnormEditField.ValueChangedFcn = createCallbackFcn(app, @RightSensorADCnormEditFieldValueChanged, true);
            app.ComputedLinePositioncmEditFieldLabel = uilabel(app.UIFigure);
            app.ComputedLinePositioncmEditFieldLabel.Text = {'Computed Line Position'; '[cm]'};
            app.ComputedLinePositioncmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputedLinePositioncmEditField.ValueDisplayFormat = '%.2f';
            app.ComputedLinePositioncmEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lineEstimationSimuliation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end